classdef controlSimulations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StopTimeEditField       matlab.ui.control.EditField
        StopTimeEditFieldLabel  matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        Slider                  matlab.ui.control.Slider
        SliderLabel             matlab.ui.control.Label
        SimulationProgress      simulink.ui.control.SimulationProgress
        SimulationControls      simulink.ui.control.SimulationControls
        TimeScope               matlab.ui.scope.TimeScope
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StopTimeEditField
        function StopTimeEditFieldValueChanged(app, event)
            value = app.StopTimeEditField.Value;
            app.Simulation.setModelParameter('StopTime', num2str(value))

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.BufferLength = 500000;
            app.TimeScope.LegendNames = {'Integrator2:1'};
            app.TimeScope.Title = 'Velocity of the Mass';
            app.TimeScope.YLabel = 'Amplitude';
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.SimulationProgress = uisimprogress(app.UIFigure);
            app.SimulationProgress.Simulation = app.Simulation;
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'k';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Value = 100;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'massspringdamper_appdes.png';
            app.StopTimeEditFieldLabel = uilabel(app.UIFigure);
            app.StopTimeEditFieldLabel.Text = 'StopTime';
            app.StopTimeEditField = uieditfield(app.UIFigure, 'text');
            app.StopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimeEditFieldValueChanged, true);
            app.StopTimeEditField.Value = '10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Integrator2:1'], app.TimeScope);
            bind(app.Slider, 'Value', app.Simulation.TunableVariables, 'k');
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controlSimulations

            % Associate the Simulink Model
            app.Simulation = simulation('ex_mass_spring_damper_appdemo');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end